classdef Simulationpouruncomposant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnregistrerButton               matlab.ui.control.Button
        NumrisationdcodageButtonGroup   matlab.ui.container.ButtonGroup
        TolrancedcodageButton_2         matlab.ui.control.Button
        TempschantillonnageButton_2     matlab.ui.control.Button
        FiltresButtonGroup              matlab.ui.container.ButtonGroup
        SeuilcomparateurHysterisisButton_2  matlab.ui.control.Button
        GainfiltreButton_2              matlab.ui.control.Button
        FrquencecoupurepassehautButton_2  matlab.ui.control.Button
        LaserButtonGroup                matlab.ui.container.ButtonGroup
        ZonederayleighButton_2          matlab.ui.control.Button
        TailledulaserButton_2           matlab.ui.control.Button
        PuissanceButton_2               matlab.ui.control.Button
        BalayageButtonGroup             matlab.ui.container.ButtonGroup
        FrequencebalayageButton_2       matlab.ui.control.Button
        anglesdebalayageButton_2        matlab.ui.control.Button
        FrquencePWMButton_2             matlab.ui.control.Button
        OuvrirButton                    matlab.ui.control.Button
        matLabel                        matlab.ui.control.Label
        NomdufichierEditField           matlab.ui.control.EditField
        NomdufichierEditFieldLabel      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExecutionencoursLamp            matlab.ui.control.Lamp
        ExecutionencoursLampLabel       matlab.ui.control.Label
        ParamtresLabel                  matlab.ui.control.Label
        stepEditField                   matlab.ui.control.NumericEditField
        stepEditFieldLabel              matlab.ui.control.Label
        ValeurdedbutEditField           matlab.ui.control.NumericEditField
        ValeurdedbutEditFieldLabel      matlab.ui.control.Label
        ValeurdefinEditField            matlab.ui.control.NumericEditField
        ValeurdefinEditFieldLabel       matlab.ui.control.Label
        PhotodiodeTransimpdanceButtonGroup  matlab.ui.container.ButtonGroup
        GaintransimpedanceButton_2      matlab.ui.control.Button
        GainphotodiodeButton_2          matlab.ui.control.Button
        airephotodiodeButton_2          matlab.ui.control.Button
        FrquencedecoupuretransimpedenceButton  matlab.ui.control.Button
        RadiometrieButtonGroup          matlab.ui.container.ButtonGroup
        FinidesurfaceButton_2           matlab.ui.control.Button
        LumirelectriqueButton_2         matlab.ui.control.Button
        LumirenaturelleButton_2         matlab.ui.control.Button
        CodeBarreButtonGroup            matlab.ui.container.ButtonGroup
        YButton                         matlab.ui.control.Button
        largeurButton                   matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        angleButton                     matlab.ui.control.Button
        HauteurButton                   matlab.ui.control.Button
        angleButton_2                   matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        RayondecourbureButton           matlab.ui.control.Button
        GalvanometresamplificateursButtonGroup  matlab.ui.container.ButtonGroup
        CoefficientdefrottementenXButton  matlab.ui.control.Button
        InertieenXButton                matlab.ui.control.Button
        GaincapteurdepositionenXButton  matlab.ui.control.Button
        ConstantedecoupleenYButton      matlab.ui.control.Button
        ConstantederappelenYButton      matlab.ui.control.Button
        CoefficientdefrottementenYButton  matlab.ui.control.Button
        InertieenYButton                matlab.ui.control.Button
        GaincapteurdepositionenYButton  matlab.ui.control.Button
        ConstantedecoupleenXButton      matlab.ui.control.Button
        ConstantederappelenXButton      matlab.ui.control.Button
        rgulateursButtonGroup           matlab.ui.container.ButtonGroup
        Kp_xButton                      matlab.ui.control.Button
        Kp_yButton                      matlab.ui.control.Button
        Ki_yButton                      matlab.ui.control.Button
        Ki_xButton                      matlab.ui.control.Button
        SimulationpouruncomposantLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        valueFisrt = 0;  % Description
        valueEND = 0;
        step = 0;
        nom_fichier = "";
        sortie = array2table([]);
        
        %general
        simulation_time = 0.1;
        fixed_step = 1e-6;
        
        %code-barres
        CodeApplication;
        code = "000000000000";
        largeur = 40;
        hauteur = 25;
        rayon = 0;
        angle_z = 0;
        angle_x = 0;
        p_x = 0;
        p_y = 0;
        p_z = -100;
        barcode_image = [];
        ratio_x = 0;
        ratio_y = 0;

        %balayage
        BalayageApplication;
        scan_freq = 10;
        pwm_freq = 30e3;
        angle_range = 15;

        %laser
        LaserApplication;
        puissance_laser = 1;
        w_0 = 0.1;
        z_r = 50;
        noyau_gaussien = [];

        %galvanomètres
        GalvoApplication;
        J_x = 8e-6;
        Kr_x = 1;
        Kf_x = 0.00565;
        Kt_x = -931;
        K0_x = 0.1;
        J_y = 7.85e-6;
        Kr_y = 1;
        Kf_y = 0.00553;
        K0_y = 0.1;
        Kt_y = -914;

        %régulateurs
        RegulApplication;
        Kp_x = -0.1074;
        Ki_x = 0.00424;
        Kp_y = -0.1545;
        Ki_y= 0.00406;

        %radiométrie
        RadiometrieApplication;
        lum_nat = 1;
        lum_elec = 1;
        fini_surface = 0;
        SNR = 100;

        %photodiode
        PhotodiodeApplication;
        aire_photodiode = 7.02;
        gain_photodiode = 0.42;
        fc_transimpedance = 10000;
        gain_transimpedance = 1.5e6;

        %filtres
        FiltreApplication;
        fc_highpass = 1200;
        gain_filtre = 100;
        seuil_comparateur = 1;

        %numerisation
        NumerisationApplication;
        temps_echantillonnage = 100;
        tolerance_decodage = 0.01;

        %Page d'erreur
        ErreurApplication;

        %simulation pour un composant
        SimuCompoApplication;

        %pour les graphiques
        capteurX_time;
        capteurX_data;
        capteurY_time;
        capteurY_data;
        regulateur_x_time;
        regulateur_x_data;
        regulateur_y_time;
        regulateur_y_data;
        filtre_passe_haut_time;
        filtre_passe_haut_data;
        sallen_key_x_time;
        sallen_key_x_data;
        sallen_key_y_time;
        sallen_key_y_data;
        comparateur_time;
        comparateur_data;


    end


    methods (Access = private)
        
        function output = Simuler(~,...
            simulation_time,... %general
            fixed_step,...
            barcode_image,... %codebarre
            ratio_x,...
            ratio_y,...
            largeur,...
            hauteur,...
            rayon,...
            angle_z,...
            angle_x,...
            p_x,...
            p_y,...
            p_z,...
            scan_freq,... %balayage
            pwm_freq,...
            angle_range,...
            puissance_laser,... %laser
            noyau_gaussien,...
            J_x, ... %galvos
            Kr_x, ...
            Kf_x, ...
            Kt_x,...
            K0_x,...
            J_y,...
            Kr_y,...
            Kf_y,...
            K0_y,...
            Kt_y,...
            Kp_x,... %regulateurs
            Ki_x,...
            Kp_y,...
            Ki_y,...
            lum_nat,... %radiometrie
            lum_elec,...
            fini_surface,...
            SNR,...
            aire_photodiode,... %photodiode-transimpendance
            gain_photodiode,...
            fc_transimpedance,...
            gain_transimpedance,...
            fc_highpass,... %filtre
            gain_filtre,...
            seuil_comparateur,...
            temps_echantillonnage,... %numerisation
            tolerance_decodage)
       
            modelworkspac = get_param('simulation','ModelWorkspace');
    
            %Fixed step
            assignin(modelworkspac,'Ts',fixed_step)
            
            %Code-barres
            assignin(modelworkspac,'barcode_image',barcode_image);
            assignin(modelworkspac,'ratio_x',ratio_x);
            assignin(modelworkspac,'ratio_y',ratio_y);
            assignin(modelworkspac,'largeur',largeur);
            assignin(modelworkspac,'hauteur',hauteur);
            assignin(modelworkspac,'rayon',rayon);
            assignin(modelworkspac,'angle_z',angle_z);
            assignin(modelworkspac,'angle_x',angle_x);
            assignin(modelworkspac,'p_x',p_x);
            assignin(modelworkspac,'p_y',p_y);
            assignin(modelworkspac,'p_z',p_z);

            %Balayage
            assignin(modelworkspac,'scan_freq',scan_freq);
            assignin(modelworkspac,'pwm_freq',pwm_freq);

            v_range = abs(0.5 - (angle_range*pi/180/K0_x/2 + 2.5)/5);
            assignin(modelworkspac,'v_range',v_range);

            %Laser
            assignin(modelworkspac,'puissance_laser',puissance_laser)
            assignin(modelworkspac,'noyau_gaussien',noyau_gaussien)

            %Galvos
            assignin(modelworkspac,'J_x',J_x);
            assignin(modelworkspac,'Kr_x',Kr_x);
            assignin(modelworkspac,'Kf_x',Kf_x);
            assignin(modelworkspac,'Kt_x',Kt_x);
            assignin(modelworkspac,'K0_x',K0_x);

            assignin(modelworkspac,'J_y',J_y);
            assignin(modelworkspac,'Kr_y',Kr_y);
            assignin(modelworkspac,'Kf_y',Kf_y);
            assignin(modelworkspac,'Kt_y',Kt_y);
            assignin(modelworkspac,'K0_y',K0_y);
            
            %Régulateurs
            assignin(modelworkspac,'Kp_x',Kp_x);
            assignin(modelworkspac,'Ki_x',Ki_x);

            assignin(modelworkspac,'Kp_y',Kp_y);
            assignin(modelworkspac,'Ki_y',Ki_y);

            %Radiometrie
            assignin(modelworkspac,'lum_nat',lum_nat);
            assignin(modelworkspac,'lum_elec',lum_elec);
            assignin(modelworkspac,'fini_surface',fini_surface);
            assignin(modelworkspac,'SNR',SNR);
            
            %Photodiode
            assignin(modelworkspac,'aire_photodiode',aire_photodiode);
            assignin(modelworkspac,'gain_photodiode',gain_photodiode);          
            assignin(modelworkspac,'fc_transimpedance',fc_transimpedance);
            assignin(modelworkspac,'gain_transimpedance',gain_transimpedance);
            
            %Filtre
            assignin(modelworkspac,'fc_highpass',fc_highpass);
            assignin(modelworkspac,'gain_filtre',gain_filtre);          
            assignin(modelworkspac,'seuil_comparateur',seuil_comparateur);

            %Numerisation
            assignin(modelworkspac,'temps_echantillonnage',temps_echantillonnage);
            assignin(modelworkspac,'tolerance_decodage',tolerance_decodage);

            %temp de simulation pour les graphes
            output = sim('simulation', 'StartTime', '0', 'StopTime', num2str(simulation_time), 'FixedStep', num2str(fixed_step));
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %faire une sauvegarde, puis loader tous les elements ici puis
           %modifier celui qui t'interesse;
        end

        % Button down function: rgulateursButtonGroup
        function rgulateursButtonGroupButtonDown(app, event)
        

        end

        % Button pushed function: Ki_yButton
        function Ki_yButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
      
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %on sort unn graphe pour chaque valeur sur un meme garphe
            %on dit si il a détetcté un code barre  ou pas
            %on enregistre les graphes
             app.sortie = Simuler(app,...
                                app.simulation_time,... %general
                                app.fixed_step,...
                                app.barcode_image,... %codebarre
                                app.ratio_x,...
                                app.ratio_y,...
                                app.largeur,...
                                app.hauteur,...
                                app.rayon,...
                                app.angle_z,...
                                app.angle_x,...
                                app.p_x,...
                                app.p_y,...
                                app.p_z,...
                                app.scan_freq,... %balayage
                                app.pwm_freq,...
                                app.angle_range,...
                                app.puissance_laser,... %laser
                                app.noyau_gaussien,...
                                app.J_x, ... %galvos
                                app.Kr_x, ...
                                app.Kf_x, ...
                                app.Kt_x,...
                                app.K0_x,...
                                app.J_y,...
                                app.Kr_y,...
                                app.Kf_y,...
                                app.K0_y,...
                                app.Kt_y,...
                                app.Kp_x,... %regulateurs
                                app.Ki_x,...
                                app.Kp_y,...
                                i,...
                                app.lum_nat,... %radiometrie
                                app.lum_elec,...
                                app.fini_surface,...
                                app.SNR,...
                                app.aire_photodiode,... %photodiode-transimpendance
                                app.gain_photodiode,...
                                app.fc_transimpedance,...
                                app.gain_transimpedance,...
                                app.fc_highpass,... %filtre
                                app.gain_filtre,...
                                app.seuil_comparateur,...
                                app.temps_echantillonnage,... %numerisation
                                app.tolerance_decodage);
        end    

           
        end

        % Value changed function: ValeurdedbutEditField
        function ValeurdedbutEditFieldValueChanged(app, event)
           % app.valueFisrt = app.ValeurdedbutEditField.Value;
            
        end

        % Value changed function: ValeurdefinEditField
        function ValeurdefinEditFieldValueChanged(app, event)
          %  app.valueEND = app.ValeurdefinEditField.Value;
            
        end

        % Value changed function: stepEditField
        function stepEditFieldValueChanged(app, event)
           % app.step = app.stepEditField.Value;
            
        end

        % Callback function
        function NomdufichierEditFieldValueChanged(app, event)
            app.nom_fichier = app.NomdufichierEditField.Value;
            
            
        end

        % Button pushed function: OuvrirButton
        function OuvrirButtonPushed(app, event)
            if app.NomdufichierEditField.Value == ""
                app.ErreurApplication = messageErreur("L'ouverture a échoué. Fichier invalide");
            else
             load(strcat(app.NomdufichierEditField.Value, '.mat'),...
                          "Simulation_time",...
                          "Fixed_step",...
                          "Code",...
                          "Largeur",...
                          "Hauteur",...
                          "Rayon",...
                          "Angle_z",...
                          "Angle_x",...
                          "P_x",...
                          "P_y",...
                          "P_z",...
                          "Scan_freq",...
                          "Pwm_freq",...
                          "Angle_range",...
                          "Puissance_laser",...
                          "W_0",...
                          "Z_r",...
                          "J_x",...
                          "Kr_x",...
                          "Kf_x",...
                          "Kt_x",...
                          "K0_x",...
                          "J_y",...
                          "Kr_y",...
                          "Kf_y",...
                          "K0_y",...
                          "Kt_y",...
                          "Kp_x",...
                          "Ki_x",...
                          "Kp_y",...
                          "Ki_y",...
                          "Lum_nat",...
                          "Lum_elec",...
                          "Fini_surface",...
                          "SNR",...
                          "Aire_photodiode",...
                          "Gain_photodiode",...
                          "Fc_transimpedance",...
                          "Gain_transimpedance",...
                          "Fc_highpass",...
                          "Gain_filtre",...
                          "Seuil_comparateur",...
                          "Temps_echantillonnage",...
                          "Tolerance_decodage");
             app.simulation_time = Simulation_time;
             app.fixed_step  = Fixed_step;
             app.code = Code;
             app.largeur = Largeur;
             app.hauteur = Hauteur;
             app.angle_z = Angle_z;
             app.rayon = Rayon;
             app.angle_x = Angle_x;
             app.p_x = P_x;
             app.p_y = P_y;
             app.p_z = P_z;
             app.scan_freq = Scan_freq;
             app.pwm_freq = Pwm_freq;
             app.angle_range = Angle_range;
             app.puissance_laser = Puissance_laser;
             app.w_0 = W_0;
             app.z_r = Z_r;
             app.J_x = J_x;
             app.Kr_x = Kr_x;
             app.Kf_x = Kf_x;
             app.Kt_x = Kt_x;
             app.K0_x = K0_x;
             app.J_y = J_y;
             app.Kr_y = Kr_y;
             app.kf_y = Kf_y;
             app.K0_y = K0_y;
             app.Kt_y = Kt_y;
             app.Kp_x = Kp_x;
             app.ki_x = Ki_x;
             app.Kp_y = Kp_y;
             app.Ki_y = Ki_y;
             app.lum_nat = Lum_nat;
             app.lum_elec = Lum_elec;
             app.fini_surface = Fini_surface;
             app.SNR = SNR;
             app.aire_photodiode = Aire_photodiode;
             app.gain_photodiode = Gain_photodiode;
             app.fc_transimpedance = Fc_transimpedance;
             app.gain_transimpedance = Gain_transimpedance;
             app.fc_highpass = Fc_highpass;
             app.gain_filtre = Gain_filtre;
             app.seuil_comparateur = Seuil_comparateur;
             app.temps_echantillonnage = Temps_echantillonnage;
             app.tolerance_decodage = Tolerance_decodage;


            end
        end

        % Button pushed function: Ki_xButton
        function Ki_xButtonPushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: Kp_xButton
        function Kp_xButtonPushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: Kp_yButton
        function Kp_yButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.v
        alueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: GaincapteurdepositionenYButton
        function GaincapteurdepositionenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: ConstantedecoupleenYButton
        function ConstantedecoupleenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: ConstantederappelenYButton
        function ConstantederappelenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: CoefficientdefrottementenYButton
        function CoefficientdefrottementenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: InertieenYButton
        function InertieenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: ConstantedecoupleenXButton
        function ConstantedecoupleenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: ConstantederappelenXButton
        function ConstantederappelenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: GaincapteurdepositionenXButton
        function GaincapteurdepositionenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: InertieenXButton
        function InertieenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: CoefficientdefrottementenXButton
        function CoefficientdefrottementenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: HauteurButton
        function HauteurButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: angleButton_2
        function angleButton_2Pushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: RayondecourbureButton
        function RayondecourbureButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: angleButton
        function angleButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: largeurButton
        function largeurButtonPushed(app, event)
             app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
             app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: LumirelectriqueButton_2
        function LumirelectriqueButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: LumirenaturelleButton_2
        function LumirenaturelleButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: FinidesurfaceButton_2
        function FinidesurfaceButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: airephotodiodeButton_2
        function airephotodiodeButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: GainphotodiodeButton_2
        function GainphotodiodeButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: GaintransimpedanceButton_2
        function GaintransimpedanceButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: FrquencedecoupuretransimpedenceButton
        function FrquencedecoupuretransimpedenceButtonPushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: FrquencePWMButton_2
        function FrquencePWMButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: anglesdebalayageButton_2
        function anglesdebalayageButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: FrequencebalayageButton_2
        function FrequencebalayageButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: FrquencecoupurepassehautButton_2
        function FrquencecoupurepassehautButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: GainfiltreButton_2
        function GainfiltreButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: SeuilcomparateurHysterisisButton_2
        function SeuilcomparateurHysterisisButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: TempschantillonnageButton_2
        function TempschantillonnageButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: TolrancedcodageButton_2
        function TolrancedcodageButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFisrt = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFisrt;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFisrt: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationpouruncomposantLabel = uilabel(app.UIFigure);
            app.SimulationpouruncomposantLabel.Text = 'Simulation pour un composant';
            app.rgulateursButtonGroup = uibuttongroup(app.UIFigure);
            app.rgulateursButtonGroup.Title = 'régulateurs';
            app.rgulateursButtonGroup.ButtonDownFcn = createCallbackFcn(app, @rgulateursButtonGroupButtonDown, true);
            app.Ki_xButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Ki_xButton.ButtonPushedFcn = createCallbackFcn(app, @Ki_xButtonPushed, true);
            app.Ki_xButton.Text = 'Ki_x';
            app.Ki_yButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Ki_yButton.ButtonPushedFcn = createCallbackFcn(app, @Ki_yButtonPushed, true);
            app.Ki_yButton.Text = 'Ki_y';
            app.Kp_yButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Kp_yButton.ButtonPushedFcn = createCallbackFcn(app, @Kp_yButtonPushed, true);
            app.Kp_yButton.Text = 'Kp_y';
            app.Kp_xButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Kp_xButton.ButtonPushedFcn = createCallbackFcn(app, @Kp_xButtonPushed, true);
            app.Kp_xButton.Text = 'Kp_x';
            app.GalvanometresamplificateursButtonGroup = uibuttongroup(app.UIFigure);
            app.GalvanometresamplificateursButtonGroup.Title = 'Galvanometres-amplificateurs';
            app.ConstantederappelenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantederappelenXButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantederappelenXButtonPushed, true);
            app.ConstantederappelenXButton.Text = 'Constante de rappel en X';
            app.ConstantedecoupleenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantedecoupleenXButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantedecoupleenXButtonPushed, true);
            app.ConstantedecoupleenXButton.Text = 'Constante de couple en X';
            app.GaincapteurdepositionenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.GaincapteurdepositionenYButton.ButtonPushedFcn = createCallbackFcn(app, @GaincapteurdepositionenYButtonPushed, true);
            app.GaincapteurdepositionenYButton.Text = 'Gain capteur de position en Y';
            app.InertieenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.InertieenYButton.ButtonPushedFcn = createCallbackFcn(app, @InertieenYButtonPushed, true);
            app.InertieenYButton.Text = 'Inertie en Y';
            app.CoefficientdefrottementenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.CoefficientdefrottementenYButton.ButtonPushedFcn = createCallbackFcn(app, @CoefficientdefrottementenYButtonPushed, true);
            app.CoefficientdefrottementenYButton.Text = 'Coefficient de frottement enY';
            app.ConstantederappelenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantederappelenYButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantederappelenYButtonPushed, true);
            app.ConstantederappelenYButton.Text = 'Constante de rappel en Y';
            app.ConstantedecoupleenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantedecoupleenYButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantedecoupleenYButtonPushed, true);
            app.ConstantedecoupleenYButton.Text = 'Constante de couple en Y';
            app.GaincapteurdepositionenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.GaincapteurdepositionenXButton.ButtonPushedFcn = createCallbackFcn(app, @GaincapteurdepositionenXButtonPushed, true);
            app.GaincapteurdepositionenXButton.Text = 'Gain capteur de position en X';
            app.InertieenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.InertieenXButton.ButtonPushedFcn = createCallbackFcn(app, @InertieenXButtonPushed, true);
            app.InertieenXButton.Text = 'Inertie en X';
            app.CoefficientdefrottementenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.CoefficientdefrottementenXButton.ButtonPushedFcn = createCallbackFcn(app, @CoefficientdefrottementenXButtonPushed, true);
            app.CoefficientdefrottementenXButton.Text = 'Coefficient de frottement enX';
            app.CodeBarreButtonGroup = uibuttongroup(app.UIFigure);
            app.CodeBarreButtonGroup.Title = 'Code Barre';
            app.RayondecourbureButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.RayondecourbureButton.ButtonPushedFcn = createCallbackFcn(app, @RayondecourbureButtonPushed, true);
            app.RayondecourbureButton.Text = 'Rayon de courbure';
            app.XButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.angleButton_2 = uibutton(app.CodeBarreButtonGroup, 'push');
            app.angleButton_2.ButtonPushedFcn = createCallbackFcn(app, @angleButton_2Pushed, true);
            app.angleButton_2.Text = 'angle β';
            app.HauteurButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.HauteurButton.ButtonPushedFcn = createCallbackFcn(app, @HauteurButtonPushed, true);
            app.HauteurButton.Text = 'Hauteur';
            app.angleButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.angleButton.ButtonPushedFcn = createCallbackFcn(app, @angleButtonPushed, true);
            app.angleButton.Text = 'angle α';
            app.ZButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.largeurButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.largeurButton.ButtonPushedFcn = createCallbackFcn(app, @largeurButtonPushed, true);
            app.largeurButton.Text = 'largeur';
            app.YButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.RadiometrieButtonGroup = uibuttongroup(app.UIFigure);
            app.RadiometrieButtonGroup.Title = 'Radiometrie';
            app.LumirenaturelleButton_2 = uibutton(app.RadiometrieButtonGroup, 'push');
            app.LumirenaturelleButton_2.ButtonPushedFcn = createCallbackFcn(app, @LumirenaturelleButton_2Pushed, true);
            app.LumirenaturelleButton_2.Text = 'Lumière naturelle';
            app.LumirelectriqueButton_2 = uibutton(app.RadiometrieButtonGroup, 'push');
            app.LumirelectriqueButton_2.ButtonPushedFcn = createCallbackFcn(app, @LumirelectriqueButton_2Pushed, true);
            app.LumirelectriqueButton_2.Text = 'Lumière électrique';
            app.FinidesurfaceButton_2 = uibutton(app.RadiometrieButtonGroup, 'push');
            app.FinidesurfaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @FinidesurfaceButton_2Pushed, true);
            app.FinidesurfaceButton_2.Text = 'Fini de surface';
            app.PhotodiodeTransimpdanceButtonGroup = uibuttongroup(app.UIFigure);
            app.PhotodiodeTransimpdanceButtonGroup.Title = 'Photodiode-Transimpédance';
            app.FrquencedecoupuretransimpedenceButton = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.FrquencedecoupuretransimpedenceButton.ButtonPushedFcn = createCallbackFcn(app, @FrquencedecoupuretransimpedenceButtonPushed, true);
            app.FrquencedecoupuretransimpedenceButton.Text = 'Fréquence de coupure transimpedence';
            app.airephotodiodeButton_2 = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.airephotodiodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @airephotodiodeButton_2Pushed, true);
            app.airephotodiodeButton_2.Text = 'aire photodiode';
            app.GainphotodiodeButton_2 = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.GainphotodiodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GainphotodiodeButton_2Pushed, true);
            app.GainphotodiodeButton_2.Text = 'Gain photodiode';
            app.GaintransimpedanceButton_2 = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.GaintransimpedanceButton_2.ButtonPushedFcn = createCallbackFcn(app, @GaintransimpedanceButton_2Pushed, true);
            app.GaintransimpedanceButton_2.Text = 'Gain transimpedance';
            app.ValeurdefinEditFieldLabel = uilabel(app.UIFigure);
            app.ValeurdefinEditFieldLabel.Text = 'Valeur de fin';
            app.ValeurdefinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValeurdefinEditField.ValueChangedFcn = createCallbackFcn(app, @ValeurdefinEditFieldValueChanged, true);
            app.ValeurdedbutEditFieldLabel = uilabel(app.UIFigure);
            app.ValeurdedbutEditFieldLabel.Text = 'Valeur de début';
            app.ValeurdedbutEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValeurdedbutEditField.ValueChangedFcn = createCallbackFcn(app, @ValeurdedbutEditFieldValueChanged, true);
            app.stepEditFieldLabel = uilabel(app.UIFigure);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepEditField.ValueChangedFcn = createCallbackFcn(app, @stepEditFieldValueChanged, true);
            app.ParamtresLabel = uilabel(app.UIFigure);
            app.ParamtresLabel.Text = 'Paramètres';
            app.ExecutionencoursLampLabel = uilabel(app.UIFigure);
            app.ExecutionencoursLampLabel.Text = 'Execution encours';
            app.ExecutionencoursLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.NomdufichierEditFieldLabel = uilabel(app.UIFigure);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.NomdufichierEditField = uieditfield(app.UIFigure, 'text');
            app.matLabel = uilabel(app.UIFigure);
            app.matLabel.Text = '.mat';
            app.OuvrirButton = uibutton(app.UIFigure, 'push');
            app.OuvrirButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirButtonPushed, true);
            app.OuvrirButton.Text = 'Ouvrir';
            app.BalayageButtonGroup = uibuttongroup(app.UIFigure);
            app.BalayageButtonGroup.Title = 'Balayage';
            app.FrquencePWMButton_2 = uibutton(app.BalayageButtonGroup, 'push');
            app.FrquencePWMButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrquencePWMButton_2Pushed, true);
            app.FrquencePWMButton_2.Text = 'Fréquence PWM';
            app.anglesdebalayageButton_2 = uibutton(app.BalayageButtonGroup, 'push');
            app.anglesdebalayageButton_2.ButtonPushedFcn = createCallbackFcn(app, @anglesdebalayageButton_2Pushed, true);
            app.anglesdebalayageButton_2.Text = 'angles de balayage';
            app.FrequencebalayageButton_2 = uibutton(app.BalayageButtonGroup, 'push');
            app.FrequencebalayageButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrequencebalayageButton_2Pushed, true);
            app.FrequencebalayageButton_2.Text = 'Frequence balayage';
            app.LaserButtonGroup = uibuttongroup(app.UIFigure);
            app.LaserButtonGroup.Title = 'Laser';
            app.PuissanceButton_2 = uibutton(app.LaserButtonGroup, 'push');
            app.PuissanceButton_2.Text = 'Puissance';
            app.TailledulaserButton_2 = uibutton(app.LaserButtonGroup, 'push');
            app.TailledulaserButton_2.Text = 'Taille du laser';
            app.ZonederayleighButton_2 = uibutton(app.LaserButtonGroup, 'push');
            app.ZonederayleighButton_2.Text = 'Zone de rayleigh';
            app.FiltresButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltresButtonGroup.Title = 'Filtres';
            app.FrquencecoupurepassehautButton_2 = uibutton(app.FiltresButtonGroup, 'push');
            app.FrquencecoupurepassehautButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrquencecoupurepassehautButton_2Pushed, true);
            app.FrquencecoupurepassehautButton_2.Text = 'Fréquence coupure passe-haut';
            app.GainfiltreButton_2 = uibutton(app.FiltresButtonGroup, 'push');
            app.GainfiltreButton_2.ButtonPushedFcn = createCallbackFcn(app, @GainfiltreButton_2Pushed, true);
            app.GainfiltreButton_2.Text = 'Gain filtre';
            app.SeuilcomparateurHysterisisButton_2 = uibutton(app.FiltresButtonGroup, 'push');
            app.SeuilcomparateurHysterisisButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeuilcomparateurHysterisisButton_2Pushed, true);
            app.SeuilcomparateurHysterisisButton_2.Text = 'Seuil comparateur Hysterisis';
            app.NumrisationdcodageButtonGroup = uibuttongroup(app.UIFigure);
            app.NumrisationdcodageButtonGroup.Title = 'Numérisation-décodage';
            app.TempschantillonnageButton_2 = uibutton(app.NumrisationdcodageButtonGroup, 'push');
            app.TempschantillonnageButton_2.ButtonPushedFcn = createCallbackFcn(app, @TempschantillonnageButton_2Pushed, true);
            app.TempschantillonnageButton_2.Text = 'Temps échantillonnage';
            app.TolrancedcodageButton_2 = uibutton(app.NumrisationdcodageButtonGroup, 'push');
            app.TolrancedcodageButton_2.ButtonPushedFcn = createCallbackFcn(app, @TolrancedcodageButton_2Pushed, true);
            app.TolrancedcodageButton_2.Text = 'Tolérance décodage';
            app.EnregistrerButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerButton.Text = 'Enregistrer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulationpouruncomposant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end